classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DebugCheckBox               matlab.ui.control.CheckBox
        RightCamera                 matlab.ui.control.Image
        LeftCamera                  matlab.ui.control.Image
        BoundsLEDLamp               matlab.ui.control.Lamp
        BoundsLEDLampLabel          matlab.ui.control.Label
        YCoordinatesEditField       matlab.ui.control.NumericEditField
        YCoordinatesEditFieldLabel  matlab.ui.control.Label
        XCoordinatesEditField       matlab.ui.control.NumericEditField
        XCoordinatesEditFieldLabel  matlab.ui.control.Label
        ZCoordinatesEditField       matlab.ui.control.NumericEditField
        ZCoordinatesEditFieldLabel  matlab.ui.control.Label
        BallControlsPanel           matlab.ui.container.Panel
        OverridePositionButton      matlab.ui.control.Button
        ZEditField_2                matlab.ui.control.EditField
        ZEditField_2Label           matlab.ui.control.Label
        YEditField_2                matlab.ui.control.EditField
        YEditField_2Label           matlab.ui.control.Label
        XEditField_2                matlab.ui.control.EditField
        XEditField_2Label           matlab.ui.control.Label
        CameraControlsPanel         matlab.ui.container.Panel
        InstantReplayCheckBox       matlab.ui.control.CheckBox
        VolleyNumberEditField       matlab.ui.control.NumericEditField
        ServeNumberEditField        matlab.ui.control.NumericEditField
        TrackServeButton            matlab.ui.control.Button
        TrackVolleyButton           matlab.ui.control.Button
        SetCameraButton             matlab.ui.control.Button
        YawEditField                matlab.ui.control.EditField
        YawEditFieldLabel           matlab.ui.control.Label
        RollEditField               matlab.ui.control.EditField
        RollEditFieldLabel          matlab.ui.control.Label
        PitchEditField              matlab.ui.control.EditField
        PitchEditFieldLabel         matlab.ui.control.Label
        Image4                      matlab.ui.control.Image
        Image3                      matlab.ui.control.Image
        Image2                      matlab.ui.control.Image
        AccuracyBasedonCameraPositionLabel  matlab.ui.control.Label
        AccuracyBasedonBallPositionLabel  matlab.ui.control.Label
        CoefficientofrestitutioneEditField  matlab.ui.control.EditField
        CoefficientofrestitutioneEditFieldLabel  matlab.ui.control.Label
        FIELDCAMERALabel            matlab.ui.control.Label
    end


    properties (Access = private)
        lampTimer = timer('Name','appLampTimer');
        lampOnOffColors = [1 0 0; .5 .5 .5];
    end

    methods (Access = private)

        function startPlay(app,path)
            addpath("../eyesAPI");
            addpath("../scripts");
            fid = fopen('../scripts/trajectoryScript.m','w');

            file = path;

            server_ip   = '127.0.0.1';     % IP address of the Unity Server
            server_port = 55001;           % Server Port of the Unity Sever
            method = @(L,R,S,C) MATLABmethod(L,R,S,C);
            mArg = [-2,-2];
            camera = @(C,P) unityCamera(C,P);
            cArg = @(T,P,S) unityLink(T,P,S);
            pose = [ 3,0,-20,90,-90,0;
                -1,0,-20,90,-90,0];
            imfig = figure(1);
            trajectory = importdata(file);
            traceFound = zeros(size(trajectory,1),size(trajectory,2)+2);
            showImages = true;

            debug = app.DebugCheckBox.Value;

            % Toggles lamp blinking state and increments counter up to 50.
            if (debug == 1)
                % Turn on lamp-blink if button is on and lamp is not already blinking
                start(app.lampTimer)
            else
                % Turn off lamp-blink if button is off
                stop(app.lampTimer)
                app.BoundsLEDLamp.Color = 'green';

            end

            % [ Skipping the irrelevant counter code. ]
            % [ See attached mlapp file if interested.]

            % Initialize
            api = eyesAPI(method,camera,mArg,cArg);
            api = api.manageServer('camera',"Start",server_ip,server_port);
            api = api.manageServer('camera',"runLink",[0,0,0,0,0,0],3);
            api.calibrate(pose);
            run("modifyScript.m");
            run("trajectoryScript.m");
            api = api.manageServer('camera',"Stop");
            saveas(imfig,'test.png');
            app.LeftCamera.ImageSource = "test.png";
            close all
        end

        function toggleLampColor(app)
            app.BoundsLEDLamp.Color = app.lampOnOffColors(app.lampTimer.UserData.state+1,:); % change color
            app.lampTimer.UserData.state = ~app.lampTimer.UserData.state; % next state
        end


        function stopTimerFcn(app)
            % Responds to stopping timer. Resets lamp state.
            app.BoundsLEDLampLabel.Text = 'In Bounds';
            app.BoundsLEDLamp.Color = app.lampOnOffColors(2,:);
        end


        %IN Out Calc

        function prediction = inOut(bounceCoord, serveType)

            %Court dimensions (Singles Court)
            courtXMinMax = [-4.192,4.212];
            courtZMinMax = [-11.928, 11.933];

            serveXMinMax = [-4.192,4.212];
            serveZMinMax = [-6.445,6.450];
            prediction = "N/A";

            if serveType == "Serve"
                if bounceCoord(3) < serveZMinMax(1) || bounceCoord(3) > serveZMinMax(2)
                    prediction = "Out";
                elseif bounceCoord(1) < serveXMinMax(1) || bounceCoord(1) > serveXMinMax(2)
                    prediction = "Out";
                else
                    prediction = "In";
                end
            elseif serveType == "Volley"
                if bounceCoord(3) < courtZMinMax(1) || bounceCoord(3) > courtZMinMax(2)
                    prediction = "Out";
                elseif bounceCoord(1) < courtXMinMax(1) || bounceCoord(1) > courtXMinMax(2)
                    prediction = "Out";
                else
                    prediction = "In";
                end
            end


        end

        function boundsPredict(app)
            % test file

            % Open the file for reading
            fid = fopen(path, 'r');

            % Initialize variables
            lowest_y = Inf; % Initialize lowest_y to positive infinity
            lowest_xyz = [];

            % Read the file line by line
            while ~feof(fid)
                % Read the next line
                line = fgetl(fid);

                % Split the line into x, y, z coordinates
                coordinates = sscanf(line, '%f %f %f');

                % Check if the y coordinate is lower than the current lowest_y
                if coordinates(2) < lowest_y
                    lowest_y = coordinates(2);
                    lowest_xyz = coordinates;
                end
            end

            % Close the file
            fclose(fid);

            % Display the result
            disp(['Lowest y coordinate: ', num2str(lowest_y)]);
            disp(['Corresponding XYZ coordinates: ', num2str(lowest_xyz(1)), ' ' ...
                , num2str(lowest_xyz(2)), ' ' , num2str(lowest_xyz(3))]);

            % Run prediction function and display results
            prediction = inOut(lowest_xyz,"Serve")
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.lampTimer.ExecutionMode = 'fixedRate';
            app.lampTimer.Period = .05; % time to stay in each state (sec)
            app.lampTimer.ObjectVisibility = 'off';  % optional, but safter
            app.lampTimer.UserData.state = false; % lamp state
            app.lampTimer.TimerFcn = @(~,~)toggleLampColor(app);
            app.lampTimer.StartFcn = @(~,~)set(app.BoundsLEDLampLabel,'Text','Out of Bounds');
            app.lampTimer.StopFcn = @(~,~)stopTimerFcn(app);
            % set lamp to default off-state
            app.BoundsLEDLamp.Color = 'green';
            app.BoundsLEDLampLabel.Text = 'In Bounds';
        end

        % Button pushed function: TrackVolleyButton
        function TrackVolleyButtonPushed(app, event)

            value = app.VolleyNumberEditField.Value;
            %Choose Trajectory
            switch value
                case 1
                    path = "../trajectories/volley1.dat";
                case 2
                    path = "../trajectories/volley2.dat";
                case 3
                    path = "../trajectories/volley3.dat";
                case 4
                    path = "../trajectories/volley4.dat";
                case 5
                    path = "../trajectories/volley5.dat";
                otherwise
                    path = "../trajectories/volley1.dat";
            end

            startPlay(app,path);

            check = app.InstantReplayCheckBox.Value;
            if (check == 1)
                startPlay(app,path);
            else
                return
            end


        end

        % Button pushed function: TrackServeButton
        function TrackServeButtonPushed(app, event)

            value = app.ServeNumberEditField.Value;

            %Choose Trajectory
            switch value
                case 1
                    path = "../trajectories/serve1.dat";
                case 2
                    path = "../trajectories/serve2.dat";
                case 3
                    path = "../trajectories/serve3.dat";
                case 4
                    path = "../trajectories/serve4.dat";
                case 5
                    path = "../trajectories/serve5.dat";
                otherwise
                    path = "../trajectories/serve1.dat";
            end

            startPlay(app,path);

            check = app.InstantReplayCheckBox.Value;
            if (check == 1)
                startPlay(app,path);
            else
                return
            end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.lampTimer)
            delete(app)
            clear
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FIELDCAMERALabel = uilabel(app.UIFigure);
            app.FIELDCAMERALabel.Text = 'FIELD CAMERA';
            app.CoefficientofrestitutioneEditFieldLabel = uilabel(app.UIFigure);
            app.CoefficientofrestitutioneEditFieldLabel.Text = 'Coefficient of restitution (e)';
            app.CoefficientofrestitutioneEditField = uieditfield(app.UIFigure, 'text');
            app.AccuracyBasedonBallPositionLabel = uilabel(app.UIFigure);
            app.AccuracyBasedonBallPositionLabel.Text = 'Accuracy Based on Ball Position';
            app.AccuracyBasedonCameraPositionLabel = uilabel(app.UIFigure);
            app.AccuracyBasedonCameraPositionLabel.Text = 'Accuracy Based on Camera Position';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Depth.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Accuracy.png');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'coefficient of restitution.png');
            app.CameraControlsPanel = uipanel(app.UIFigure);
            app.CameraControlsPanel.Title = 'Camera Controls';
            app.PitchEditFieldLabel = uilabel(app.CameraControlsPanel);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.CameraControlsPanel, 'text');
            app.RollEditFieldLabel = uilabel(app.CameraControlsPanel);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.CameraControlsPanel, 'text');
            app.YawEditFieldLabel = uilabel(app.CameraControlsPanel);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.CameraControlsPanel, 'text');
            app.SetCameraButton = uibutton(app.CameraControlsPanel, 'push');
            app.SetCameraButton.Text = 'Set Camera';
            app.TrackVolleyButton = uibutton(app.CameraControlsPanel, 'push');
            app.TrackVolleyButton.ButtonPushedFcn = createCallbackFcn(app, @TrackVolleyButtonPushed, true);
            app.TrackVolleyButton.Text = 'Track Volley';
            app.TrackServeButton = uibutton(app.CameraControlsPanel, 'push');
            app.TrackServeButton.ButtonPushedFcn = createCallbackFcn(app, @TrackServeButtonPushed, true);
            app.TrackServeButton.Text = 'Track Serve';
            app.ServeNumberEditField = uieditfield(app.CameraControlsPanel, 'numeric');
            app.ServeNumberEditField.Limits = [1 5];
            app.ServeNumberEditField.Value = 1;
            app.VolleyNumberEditField = uieditfield(app.CameraControlsPanel, 'numeric');
            app.VolleyNumberEditField.Limits = [1 5];
            app.VolleyNumberEditField.Value = 1;
            app.InstantReplayCheckBox = uicheckbox(app.CameraControlsPanel);
            app.InstantReplayCheckBox.Text = 'Instant Replay';
            app.BallControlsPanel = uipanel(app.UIFigure);
            app.BallControlsPanel.Title = 'Ball Controls';
            app.XEditField_2Label = uilabel(app.BallControlsPanel);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.BallControlsPanel, 'text');
            app.YEditField_2Label = uilabel(app.BallControlsPanel);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.BallControlsPanel, 'text');
            app.ZEditField_2Label = uilabel(app.BallControlsPanel);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_2 = uieditfield(app.BallControlsPanel, 'text');
            app.OverridePositionButton = uibutton(app.BallControlsPanel, 'push');
            app.OverridePositionButton.Text = 'Override Position';
            app.ZCoordinatesEditFieldLabel = uilabel(app.UIFigure);
            app.ZCoordinatesEditFieldLabel.Text = 'Z Coordinates';
            app.ZCoordinatesEditField = uieditfield(app.UIFigure, 'numeric');
            app.XCoordinatesEditFieldLabel = uilabel(app.UIFigure);
            app.XCoordinatesEditFieldLabel.Text = 'X Coordinates';
            app.XCoordinatesEditField = uieditfield(app.UIFigure, 'numeric');
            app.YCoordinatesEditFieldLabel = uilabel(app.UIFigure);
            app.YCoordinatesEditFieldLabel.Text = 'Y Coordinates';
            app.YCoordinatesEditField = uieditfield(app.UIFigure, 'numeric');
            app.BoundsLEDLampLabel = uilabel(app.UIFigure);
            app.BoundsLEDLampLabel.Text = 'Bounds LED';
            app.BoundsLEDLamp = uilamp(app.UIFigure);
            app.LeftCamera = uiimage(app.UIFigure);
            app.RightCamera = uiimage(app.UIFigure);
            app.DebugCheckBox = uicheckbox(app.UIFigure);
            app.DebugCheckBox.Text = 'Debug';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end